classdef LamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        InstructionsLabel               matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_2  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_3  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_4  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_5  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_6  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_7  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_8  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_9  matlab.ui.control.Label
        SelecttypeofanalysistobecarriedoutLabel_10  matlab.ui.control.Label
        FaceSheetLaminateTab            matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LaminateFailurePropertiesLabel  matlab.ui.control.Label
        FaceSheetFailProp               matlab.ui.control.Table
        FaceSheetResults                matlab.ui.control.Table
        PushOnceFinishedButton          matlab.ui.control.Button
        LaminateMaterialDimensionPropertiesLabel  matlab.ui.control.Label
        FaceSheetMatProp                matlab.ui.control.Table
        FaceSheetTheta                  matlab.ui.control.Table
        FaceSheetNumPliesEditField      matlab.ui.control.NumericEditField
        NumberOfPliesEditFieldLabel     matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        BlastWaveParametersLabel        matlab.ui.control.Label
        FaceSheetBlastWave              matlab.ui.control.Table
        InputFileNameEditFieldLabel     matlab.ui.control.Label
        InputFileNameEditField          matlab.ui.control.EditField
        LoadInputsButton                matlab.ui.control.Button
        SandwichSheetLaminateTab        matlab.ui.container.Tab
        SanFSMatPropSan                 matlab.ui.control.Table
        FaceSheetMaterialDimensionPropertiesLabel  matlab.ui.control.Label
        SanPushOnceFinishedButton       matlab.ui.control.Button
        SanResults                      matlab.ui.control.Table
        SanFailProp                     matlab.ui.control.Table
        LaminateFailurePropertiesLabel_2  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        SanCMatPropSan                  matlab.ui.control.Table
        CoreMaterialDimensionPropertiesLabel  matlab.ui.control.Label
        ResultsLabel_2                  matlab.ui.control.Label
        FaceSheetOrientationEditFieldLabel  matlab.ui.control.Label
        FaceSheetOrientationEditField   matlab.ui.control.NumericEditField
        BlastWaveParametersLabel_2      matlab.ui.control.Label
        SanBlastWave                    matlab.ui.control.Table
        Label_15                        matlab.ui.control.Label
        InputFileNameEditField_2Label   matlab.ui.control.Label
        SanInputFileNameEditField       matlab.ui.control.EditField
        SanLoadInputsButton             matlab.ui.control.Button
        LaminateFailureOrientationTab   matlab.ui.container.Tab
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        LaminateFailurePropertiesLabel_3  matlab.ui.control.Label
        LamFailProp                     matlab.ui.control.Table
        LamFailResults                  matlab.ui.control.Table
        LamFailHoffman                  matlab.ui.control.Button
        LaminateMaterialDimensionPropertiesLabel_2  matlab.ui.control.Label
        LamFailMatProp                  matlab.ui.control.Table
        NumberOfPliesEditFieldLabel_2   matlab.ui.control.Label
        NumberOfPliesEditField          matlab.ui.control.NumericEditField
        LamFailTsaiHill                 matlab.ui.control.Button
        LamFailTsaiWu                   matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        ResultsLabel_3                  matlab.ui.control.Label
        InputFileNameEditField_2Label_2  matlab.ui.control.Label
        LamFailInputFileNameEditField   matlab.ui.control.EditField
        LamFailLoadInputsButton         matlab.ui.control.Button
        BlastWavePreviewTab             matlab.ui.container.Tab
        BlastWaveParametersLabel_3      matlab.ui.control.Label
        PreviewBlastWave                matlab.ui.control.Table
        PressOnceFinishedforPreviewButton  matlab.ui.control.Button
        PressToBeginButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FaceSheetNumPliesEditField
        function FaceSheetNumPliesEditFieldValueChanged(app, event)
        NumPlies = app.FaceSheetNumPliesEditField.Value;
        GUIInput.NumPlies = NumPlies;
        
        app.FaceSheetTheta.ColumnName = {'Theta(Degrees)'};
        app.FaceSheetTheta.Data=zeros(NumPlies,1);
        app.FaceSheetTheta.ColumnEditable = true;
        for i = 1:NumPlies 
            formatSpec = 'Ply#%d';
            str = sprintf(formatSpec,i); 
            app.FaceSheetTheta.RowName(i,:) = {str};
        end

        app.FaceSheetBlastWave.ColumnName = {'Pm','Alpha','Tp'};
        app.FaceSheetBlastWave.Data=zeros(1,3);
        app.FaceSheetBlastWave.ColumnEditable = true;
        
        app.FaceSheetMatProp.ColumnName = {'E1(Pa)','E2(Pa)','G12(Pa)','v12','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.FaceSheetMatProp.Data=zeros(1,8);
        app.FaceSheetMatProp.ColumnEditable = true;
        
        app.FaceSheetResults.ColumnName = {'MaxStressF1Xt', 'MaxStressF1Xc', 'MaxStressF2Yt', 'MaxStressF2Yc', 'MaxStressF12S', 'MaxStrainsF1Xt', 'MaxStrainsF1Xc', 'MaxStrainsF2Yt', 'MaxStrainsF2Yc', 'MaxStrainsF12S', 'MatStresses1', 'MatStresses2', 'MatStresses3','MatStrains1',' MatStrains2','MatStrains3','Hoffman','TsaiHill','TsaiWu'};
        app.FaceSheetResults.Data=zeros(1,19);
        app.FaceSheetResults.ColumnEditable = false;
        
        app.FaceSheetFailProp.ColumnName = {'Xt','Xc','Yt','Yc','S','ext','exc','eyt','eyc','es'};
        app.FaceSheetFailProp.Data=zeros(1,10);
        app.FaceSheetFailProp.ColumnEditable = true;
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            
        end

        % Button pushed function: PushOnceFinishedButton
        function PushOnceFinishedButtonPushed(app, event)
            h = msgbox('Calculating, Please Wait...');
            
            NumPlies = app.FaceSheetNumPliesEditField.Value;
            GUIInput.NumPlies = NumPlies;
            t1 =  app.FaceSheetTheta.Data;
            t2 =  app.FaceSheetMatProp.Data;
            t3 =  app.FaceSheetBlastWave.Data;
            t4 =  app.FaceSheetFailProp.Data;
            GUIInput.t1 = t1;
            GUIInput.t2 = t2;
            GUIInput.t3 = t3;
            GUIInput.t4 = t4;
            
            app.FaceSheetResults.ColumnName = {'t/ms', 'pt/kPa', 'w/mm', 'MaxStressF1Xt', 'MaxStressF1Xc', 'MaxStressF2Yt', 'MaxStressF2Yc', 'MaxStressF12S', 'MaxStrainsF1Xt', 'MaxStrainsF1Xc', 'MaxStrainsF2Yt', 'MaxStrainsF2Yc', 'MaxStrainsF12S', 'MatStresses1', 'MatStresses2', 'MatStresses3','MatStrains1',' MatStrains2','MatStrains3','Hoffman','TsaiHill','TsaiWu'};
            app.FaceSheetResults.Data=zeros(1,21);
            
            
            output = GUIFaceSheetDeflection(GUIInput);
            
            delete(h)
            msgbox('Finished!');
            
            app.FaceSheetResults.Data = (output);
        end

        % Size changed function: SandwichSheetLaminateTab
        function SandwichSheetLaminateTabSizeChanged(app, event)

            
        end

        % Callback function
        function FaceSheetOrientationEditFieldValueChanged(app, event)
        value = app.FaceSheetOrientationEditField.Value;
            
        app.SanFSMatPropSan.ColumnName = {'E1(Pa)','E2(Pa)','G12(Pa)','v12','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.SanFSMatPropSan.Data=zeros(1,8);
        app.SanFSMatPropSan.ColumnEditable = true;
        
        app.SanCMatPropSan.ColumnName = {'E1c(Pa)','E2c(Pa)','G12c(Pa)','v12c','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.SanCMatPropSan.Data=zeros(1,8);
        app.SanCMatPropSan.ColumnEditable = true;
               
        app.SanFailProp.ColumnName = {'Xt','Xc','Yt','Yc','S','ext','exc','eyt','eyc','es'};
        app.SanFailProp.Data=zeros(1,10);
        app.SanFailProp.ColumnEditable = true;        
            
        app.SanResults.ColumnName = {'t/ms','w/mm', 'MaxStressF1Xt', 'MaxStressF1Xc', 'MaxStressF2Yt', 'MaxStressF2Yc', 'MaxStressF12S', 'MaxStrainsF1Xt', 'MaxStrainsF1Xc', 'MaxStrainsF2Yt', 'MaxStrainsF2Yc', 'MaxStrainsF12S', 'MatStresses1', 'MatStresses2', 'MatStresses3','MatStrains1',' MatStrains2','MatStrains3','Hoffman','TsaiHill','TsaiWu'};
        app.SanResults.Data=zeros(1,21);
        app.SanResults.ColumnEditable = false;
        end

        % Button pushed function: SanPushOnceFinishedButton
        function SanPushOnceFinishedButtonPushed(app, event)
        h = msgbox('Calculating, Please Wait...');
            
            Orientation = app.FaceSheetOrientationEditField.Value;
            GUIInput.Orientation = Orientation;
            
            t1s =  app.SanFSMatPropSan.Data;
            t2s =  app.SanCMatPropSan.Data;
            t3s =  app.SanFailProp.Data;
            t4s =  app.SanBlastWave.Data;

            GUIInput.t1s = t1s;
            GUIInput.t2s = t2s;
            GUIInput.t3s = t3s;     
            GUIInput.t4s = t4s;
            
        delete(h)
        msgbox('Finished!');
            
            output = GUISandwichDisp(GUIInput);
            app.SanResults.Data = (output);            
        end

        % Value changed function: NumberOfPliesEditField
        function NumberOfPliesEditFieldValueChanged(app, event)
            value = app.NumberOfPliesEditField.Value;
                        
        app.LamFailMatProp.ColumnName = {'E1(Pa)','E2(Pa)','G12(Pa)','v12','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.LamFailMatProp.Data=zeros(1,8);
        app.LamFailMatProp.ColumnEditable = true;
               
        app.LamFailProp.ColumnName = {'Xt','Xc','Yt','Yc','S','ext','exc','eyt','eyc','es'};
        app.LamFailProp.Data=zeros(1,10);
        app.LamFailProp.ColumnEditable = true;        
        
        end

        % Button pushed function: LamFailHoffman
        function LamFailHoffmanPushed(app, event)
        h = msgbox('Calculating, Please Wait...');   
        
            app.LamFailResults.ColumnName = {'Theta','Failure Pressure (Hoffman)'};
            app.LamFailResults.Data=zeros(1,2);
            app.LamFailResults.ColumnEditable = false;
            
            NumPlies = app.NumberOfPliesEditField.Value;
            GUIInput.NumPlies = NumPlies;
            
            t1LF =  app.LamFailMatProp.Data;
            t2LF =  app.LamFailProp.Data;

            GUIInput.t1LF = t1LF;
            GUIInput.t2LF = t2LF;       
            
        delete(h)
        msgbox('Finished!');
            
            output = GUILaminate_Orientation_Hoffman(GUIInput);
            app.LamFailResults.Data = (output);                  
        end

        % Button pushed function: LamFailTsaiHill
        function LamFailTsaiHillPushed(app, event)
        h = msgbox('Calculating, Please Wait...');             
            app.LamFailResults.ColumnName = {'Theta','Failure Pressure (Tsai-Hill)'};
            app.LamFailResults.Data=zeros(1,2);
            app.LamFailResults.ColumnEditable = false;
            
            NumPlies = app.NumberOfPliesEditField.Value;
            GUIInput.NumPlies = NumPlies;
            
            t1LF =  app.LamFailMatProp.Data;
            t2LF =  app.LamFailProp.Data;

            GUIInput.t1LF = t1LF;
            GUIInput.t2LF = t2LF;         
            
        delete(h)
        msgbox('Finished!');            
            
            output = GUILaminate_Orientation_TsaiHill(GUIInput);
            app.LamFailResults.Data = (output);                
        end

        % Button pushed function: LamFailTsaiWu
        function LamFailTsaiWuPushed(app, event)
        h = msgbox('Calculating, Please Wait...');              
            app.LamFailResults.ColumnName = {'Theta','Failure Pressure (Tsai-Hill)'};
            app.LamFailResults.Data=zeros(1,2);
            app.LamFailResults.ColumnEditable = false;
            
            NumPlies = app.NumberOfPliesEditField.Value;
            GUIInput.NumPlies = NumPlies;
            
            t1LF =  app.LamFailMatProp.Data;
            t2LF =  app.LamFailProp.Data;

            GUIInput.t1LF = t1LF;
            GUIInput.t2LF = t2LF;         
          
        delete(h)
        msgbox('Finished!');              
            
            output = GUILaminate_Orientation_TsaiWu(GUIInput);
            app.LamFailResults.Data = (output);               
        end

        % Value changed function: FaceSheetOrientationEditField
        function FaceSheetOrientationEditFieldValueChanged2(app, event)
            value = app.FaceSheetOrientationEditField.Value;
            
        app.SanBlastWave.ColumnName = {'Pm','Alpha','Tp'};
        app.SanBlastWave.Data=zeros(1,3);
        app.SanBlastWave.ColumnEditable = true;
            
        app.SanFSMatPropSan.ColumnName = {'E1(Pa)','E2(Pa)','G12(Pa)','v12','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.SanFSMatPropSan.Data=zeros(1,8);
        app.SanFSMatPropSan.ColumnEditable = true;
        
        app.SanCMatPropSan.ColumnName = {'E1c(Pa)','E2c(Pa)','G12c(Pa)','v12c','a(m)','b(m)','h(m)','Rho(kg/m^-3)'};
        app.SanCMatPropSan.Data=zeros(1,8);
        app.SanCMatPropSan.ColumnEditable = true;
               
        app.SanFailProp.ColumnName = {'Xt','Xc','Yt','Yc','S','ext','exc','eyt','eyc','es'};
        app.SanFailProp.Data=zeros(1,10);
        app.SanFailProp.ColumnEditable = true;        
            
        app.SanResults.ColumnName = {'t/ms','w/mm', 'MaxStressF1Xt', 'MaxStressF1Xc', 'MaxStressF2Yt', 'MaxStressF2Yc', 'MaxStressF12S', 'MaxStrainsF1Xt', 'MaxStrainsF1Xc', 'MaxStrainsF2Yt', 'MaxStrainsF2Yc', 'MaxStrainsF12S', 'MatStresses1', 'MatStresses2', 'MatStresses3','MatStrains1',' MatStrains2','MatStrains3','Hoffman','TsaiHill','TsaiWu'};
        app.SanResults.Data=zeros(1,21);
        app.SanResults.ColumnEditable = false;            
        end

        % Button pushed function: LoadInputsButton
        function LoadInputsButtonPushed(app, event)
            % get input file from gui field
            input_file = app.InputFileNameEditField.Value;
            
            % Make sure no file name ext
            [~,fname,~] = fileparts(input_file);
            
            % Call Function to get Structure
            Inputs = feval(fname);
            
            % Write Inpouts to GUI Field
            app.FaceSheetNumPliesEditField.Value = Inputs.No_Plies;
            app.FaceSheetTheta.Data = Inputs.Theta';
            app.FaceSheetMatProp.Data = [Inputs.E1 Inputs.E2 Inputs.G12 Inputs.v12 Inputs.a Inputs.b Inputs.h Inputs.rho];
            app.FaceSheetFailProp.Data = [Inputs.Xt Inputs.Xc Inputs.Yt Inputs.Yc Inputs.S Inputs.ext Inputs.exc Inputs.eyt Inputs.eyc Inputs.es]; 
            app.FaceSheetBlastWave.Data = [Inputs.Pm Inputs.Alpha Inputs.tp];
            
        end

        % Button pushed function: SanLoadInputsButton
        function SanLoadInputsButtonPushed(app, event)
            % get input file from gui field
            input_file = app.SanInputFileNameEditField.Value;
            
            % Make sure no file name ext
            [~,fname,~] = fileparts(input_file);
            
            % Call Function to get Structure
            Inputs = feval(fname);
            
            % Write Inpouts to GUI Field
            app.FaceSheetOrientationEditField.Value = Inputs.Theta;
            app.SanFSMatPropSan.Data = [Inputs.E1 Inputs.E2 Inputs.G12 Inputs.v12 Inputs.a Inputs.b Inputs.tf Inputs.rhof];
            app.SanCMatPropSan.Data = [Inputs.E1c Inputs.E2c Inputs.G12c Inputs.v12c Inputs.a Inputs.b Inputs.tc Inputs.rhoc];
            app.SanFailProp.Data = [Inputs.Xt Inputs.Xc Inputs.Yt Inputs.Yc Inputs.S Inputs.ext Inputs.exc Inputs.eyt Inputs.eyc Inputs.es]; 
            app.SanBlastWave.Data = [Inputs.Pm Inputs.Alpha Inputs.tp];            
        end

        % Button pushed function: LamFailLoadInputsButton
        function LamFailLoadInputsButtonPushed(app, event)
            % get input file from gui field
            input_file = app.InputFileNameEditField.Value;
            
            % Make sure no file name ext
            [~,fname,~] = fileparts(input_file);
            
            % Call Function to get Structure
            Inputs = feval(fname);
            
            % Write Inpouts to GUI Field
            app.NumberOfPliesEditField.Value = Inputs.No_Plies;
            app.LamFailMatProp.Data = [Inputs.E1 Inputs.E2 Inputs.G12 Inputs.v12 Inputs.a Inputs.b Inputs.h Inputs.rho];
            app.LamFailProp.Data = [Inputs.Xt Inputs.Xc Inputs.Yt Inputs.Yc Inputs.S Inputs.ext Inputs.exc Inputs.eyt Inputs.eyc Inputs.es];  
        end

        % Button pushed function: PressToBeginButton
        function PressToBeginButtonPushed(app, event)

        app.PreviewBlastWave.ColumnName = {'Pm','Alpha','Tp'};
        app.PreviewBlastWave.Data=zeros(1,3);
        app.PreviewBlastWave.ColumnEditable = true;    
        
        end

        % Button pushed function: PressOnceFinishedforPreviewButton
        function PressOnceFinishedforPreviewButtonPushed(app, event)
        h = msgbox('Calculating, Please Wait...');   
           
            t1p =  app.PreviewBlastWave.Data;

            GUIInput.t1p = t1p;      
            
        delete(h)
        msgbox('Finished!');
            
            output = GUIPressurePreview(GUIInput);
            title(app.UIAxes,'Blast Wave (Friedlander Model)')
            xlabel(app.UIAxes,'t/ms') 
            ylabel(app.UIAxes,'Pressure/Pa')
            plot(app.UIAxes,output.t,output.pt)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.InstructionsLabel = uilabel(app.InstructionsTab);
            app.InstructionsLabel.Text = 'Instructions';
            app.SelecttypeofanalysistobecarriedoutLabel = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel.Text = '1) Select type of analysis to be carried out;';
            app.SelecttypeofanalysistobecarriedoutLabel_2 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_2.Text = 'a) Analysis including any of the following parameters for a laminate under blast loading conditions;';
            app.SelecttypeofanalysistobecarriedoutLabel_3 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_3.Text = '-Mid-Plate Deflection or Maximum Stress/ Strain, Hoffman,Tsai-Hill and Tsai-Wu Failure Criteria for a Face Sheet Laminate, use the tab labelled, "Face Sheet Laminate"';
            app.SelecttypeofanalysistobecarriedoutLabel_4 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_4.Text = '-Mid-Plate Deflection or Maximum Stress/ Strain, Hoffman,Tsai-Hill and Tsai-Wu Failure Criteria for a Sandwich Sheet Laminate, use the tab labelled, "Sandwich Sheet Laminate"';
            app.SelecttypeofanalysistobecarriedoutLabel_5 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_5.Text = 'b) Analysis pertaining to the Hoffman,Tsai-Hill and Tsai-Wu Failure Criteria values of a Face Sheet Laminate under blast loading conditions with varying orientation from 0-90 degrees; use the tab labelled, "Laminate Failure Orientation" ';
            app.SelecttypeofanalysistobecarriedoutLabel_6 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_6.Text = '2) To use each tab, simply fill out the laminate''s characteristics in the order they are numerically labelled, starting at 1 and ending in 4. For the "Laminate Failure Orientation" tab, in the final step simply click on the Failure Criterion that is required.';
            app.SelecttypeofanalysistobecarriedoutLabel_7 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_7.Text = '3) All data produced from each tab can be accessed through 2 means;';
            app.SelecttypeofanalysistobecarriedoutLabel_8 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_8.Text = 'a) Simply view the required numbers within the tabs respective ''results'' table';
            app.SelecttypeofanalysistobecarriedoutLabel_9 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_9.Text = 'b) Or each table can be accessed through the excel file which is saved within the local Matlab Path. The Excel file name for each set of results is labelled as follows;';
            app.SelecttypeofanalysistobecarriedoutLabel_10 = uilabel(app.InstructionsTab);
            app.SelecttypeofanalysistobecarriedoutLabel_10.Text = '-Mid-Plate Deflection or Maximum Stress/ Strain, Hoffman,Tsai-Hill and Tsai-Wu Failure Criteria for a Face Sheet Laminate, use the tab labelled, "Face Sheet Laminate"';
            app.FaceSheetLaminateTab = uitab(app.TabGroup);
            app.FaceSheetLaminateTab.Title = 'Face Sheet Laminate';
            app.Label_4 = uilabel(app.FaceSheetLaminateTab);
            app.Label_4.Text = '5.';
            app.Label_3 = uilabel(app.FaceSheetLaminateTab);
            app.Label_3.Text = '4.';
            app.Label_2 = uilabel(app.FaceSheetLaminateTab);
            app.Label_2.Text = '2.';
            app.Label = uilabel(app.FaceSheetLaminateTab);
            app.Label.Text = '1.';
            app.LaminateFailurePropertiesLabel = uilabel(app.FaceSheetLaminateTab);
            app.LaminateFailurePropertiesLabel.Text = 'Laminate Failure Properties';
            app.FaceSheetFailProp = uitable(app.FaceSheetLaminateTab);
            app.FaceSheetFailProp.ColumnName = {'Xt'; 'Xc'; 'Yt'; 'Yc'; 'S'; 'ext'; 'exc'; 'eyt'; 'eyc'; 'es'};
            app.FaceSheetFailProp.RowName = {};
            app.FaceSheetFailProp.ColumnEditable = true;
            app.FaceSheetResults = uitable(app.FaceSheetLaminateTab);
            app.FaceSheetResults.ColumnName = {'MaxStressF1Xt'; 'MaxStressF1Xc'; 'MaxStressF2Yt'; 'MaxStressF2Yc'; 'MaxStressF12S'; 'MaxStrainsF1Xt'; 'MaxStrainsF1Xc'; 'MaxStrainsF2Yt'; 'MaxStrainsF2Yc'; 'MaxStrainsF12S'; 'MatStresses1'; 'MatStresses2'; 'MatStresses3'; 'MatStrains1'; 'MatStrains2'; 'MatStrains3'; 'Hoffman'; 'Tsai-Hill'; 'Tsai-Wu'};
            app.FaceSheetResults.RowName = {};
            app.PushOnceFinishedButton = uibutton(app.FaceSheetLaminateTab, 'push');
            app.PushOnceFinishedButton.ButtonPushedFcn = createCallbackFcn(app, @PushOnceFinishedButtonPushed, true);
            app.PushOnceFinishedButton.Text = 'Push Once Finished';
            app.LaminateMaterialDimensionPropertiesLabel = uilabel(app.FaceSheetLaminateTab);
            app.LaminateMaterialDimensionPropertiesLabel.Text = 'Laminate Material & Dimension Properties';
            app.FaceSheetMatProp = uitable(app.FaceSheetLaminateTab);
            app.FaceSheetMatProp.ColumnName = {'E1(Pa)'; 'E2(Pa)'; 'G12(Pa)'; 'v12'; 'a(m)'; 'b(m)'; 'h(m)'; 'Rho(kg/m^-3)'};
            app.FaceSheetMatProp.RowName = {'Laminate'};
            app.FaceSheetMatProp.ColumnEditable = true;
            app.FaceSheetTheta = uitable(app.FaceSheetLaminateTab);
            app.FaceSheetTheta.ColumnName = {'Theta(Degrees)'};
            app.FaceSheetTheta.RowName = {};
            app.FaceSheetNumPliesEditField = uieditfield(app.FaceSheetLaminateTab, 'numeric');
            app.FaceSheetNumPliesEditField.ValueChangedFcn = createCallbackFcn(app, @FaceSheetNumPliesEditFieldValueChanged, true);
            app.NumberOfPliesEditFieldLabel = uilabel(app.FaceSheetLaminateTab);
            app.NumberOfPliesEditFieldLabel.Text = 'Number Of Plies';
            app.ResultsLabel = uilabel(app.FaceSheetLaminateTab);
            app.ResultsLabel.Text = 'Results';
            app.Label_14 = uilabel(app.FaceSheetLaminateTab);
            app.Label_14.Text = '3.';
            app.BlastWaveParametersLabel = uilabel(app.FaceSheetLaminateTab);
            app.BlastWaveParametersLabel.Text = 'Blast Wave Parameters';
            app.FaceSheetBlastWave = uitable(app.FaceSheetLaminateTab);
            app.FaceSheetBlastWave.ColumnName = {'Pm'; 'Alpha'; 'Tp'};
            app.FaceSheetBlastWave.RowName = {'Laminate'};
            app.FaceSheetBlastWave.ColumnEditable = true;
            app.InputFileNameEditFieldLabel = uilabel(app.FaceSheetLaminateTab);
            app.InputFileNameEditFieldLabel.Text = 'Input File Name';
            app.InputFileNameEditField = uieditfield(app.FaceSheetLaminateTab, 'text');
            app.LoadInputsButton = uibutton(app.FaceSheetLaminateTab, 'push');
            app.LoadInputsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputsButtonPushed, true);
            app.LoadInputsButton.Text = 'Load Inputs';
            app.SandwichSheetLaminateTab = uitab(app.TabGroup);
            app.SandwichSheetLaminateTab.SizeChangedFcn = createCallbackFcn(app, @SandwichSheetLaminateTabSizeChanged, true);
            app.SandwichSheetLaminateTab.Title = 'Sandwich Sheet Laminate';
            app.SanFSMatPropSan = uitable(app.SandwichSheetLaminateTab);
            app.SanFSMatPropSan.ColumnName = {'E1(Pa)'; 'E2(Pa)'; 'G12(Pa)'; 'v12'; 'a(m)'; 'b(m)'; 'tf(m)'; 'Rho f (kg/m^-3)'};
            app.SanFSMatPropSan.RowName = {'Laminate'};
            app.SanFSMatPropSan.ColumnEditable = true;
            app.FaceSheetMaterialDimensionPropertiesLabel = uilabel(app.SandwichSheetLaminateTab);
            app.FaceSheetMaterialDimensionPropertiesLabel.Text = 'Face Sheet Material & Dimension Properties';
            app.SanPushOnceFinishedButton = uibutton(app.SandwichSheetLaminateTab, 'push');
            app.SanPushOnceFinishedButton.ButtonPushedFcn = createCallbackFcn(app, @SanPushOnceFinishedButtonPushed, true);
            app.SanPushOnceFinishedButton.Text = 'Push Once Finished';
            app.SanResults = uitable(app.SandwichSheetLaminateTab);
            app.SanResults.ColumnName = {'MaxStressF1Xt'; 'MaxStressF1Xc'; 'MaxStressF2Yt'; 'MaxStressF2Yc'; 'MaxStressF12S'; 'MaxStrainsF1Xt'; 'MaxStrainsF1Xc'; 'MaxStrainsF2Yt'; 'MaxStrainsF2Yc'; 'MaxStrainsF12S'; 'MatStresses1'; 'MatStresses2'; 'MatStresses3'; 'MatStrains1'; 'MatStrains2'; 'MatStrains3'; 'Hoffman'; 'Tsai-Hill'; 'Tsai-Wu'};
            app.SanResults.RowName = {};
            app.SanFailProp = uitable(app.SandwichSheetLaminateTab);
            app.SanFailProp.ColumnName = {'Xt'; 'Xc'; 'Yt'; 'Yc'; 'S'; 'ext'; 'exc'; 'eyt'; 'eyc'; 'es'};
            app.SanFailProp.RowName = {};
            app.LaminateFailurePropertiesLabel_2 = uilabel(app.SandwichSheetLaminateTab);
            app.LaminateFailurePropertiesLabel_2.Text = 'Laminate Failure Properties';
            app.Label_5 = uilabel(app.SandwichSheetLaminateTab);
            app.Label_5.Text = '1.';
            app.Label_6 = uilabel(app.SandwichSheetLaminateTab);
            app.Label_6.Text = '3.';
            app.Label_7 = uilabel(app.SandwichSheetLaminateTab);
            app.Label_7.Text = '4.';
            app.Label_8 = uilabel(app.SandwichSheetLaminateTab);
            app.Label_8.Text = '5.';
            app.SanCMatPropSan = uitable(app.SandwichSheetLaminateTab);
            app.SanCMatPropSan.ColumnName = {'E1(Pa)'; 'E2(Pa)'; 'G12(Pa)'; 'v12'; 'a(m)'; 'b(m)'; 'tc(m)'; 'Rho c (kg/m^-3)'};
            app.SanCMatPropSan.RowName = {'Laminate'};
            app.SanCMatPropSan.ColumnEditable = true;
            app.CoreMaterialDimensionPropertiesLabel = uilabel(app.SandwichSheetLaminateTab);
            app.CoreMaterialDimensionPropertiesLabel.Text = 'Core Material & Dimension Properties';
            app.ResultsLabel_2 = uilabel(app.SandwichSheetLaminateTab);
            app.ResultsLabel_2.Text = 'Results';
            app.FaceSheetOrientationEditFieldLabel = uilabel(app.SandwichSheetLaminateTab);
            app.FaceSheetOrientationEditFieldLabel.Text = 'Face Sheet Orientation';
            app.FaceSheetOrientationEditField = uieditfield(app.SandwichSheetLaminateTab, 'numeric');
            app.FaceSheetOrientationEditField.ValueChangedFcn = createCallbackFcn(app, @FaceSheetOrientationEditFieldValueChanged2, true);
            app.BlastWaveParametersLabel_2 = uilabel(app.SandwichSheetLaminateTab);
            app.BlastWaveParametersLabel_2.Text = 'Blast Wave Parameters';
            app.SanBlastWave = uitable(app.SandwichSheetLaminateTab);
            app.SanBlastWave.ColumnName = {'Pm'; 'Alpha'; 'Tp'};
            app.SanBlastWave.RowName = {'Laminate'};
            app.SanBlastWave.ColumnEditable = true;
            app.Label_15 = uilabel(app.SandwichSheetLaminateTab);
            app.Label_15.Text = '2.';
            app.InputFileNameEditField_2Label = uilabel(app.SandwichSheetLaminateTab);
            app.InputFileNameEditField_2Label.Text = 'Input File Name';
            app.SanInputFileNameEditField = uieditfield(app.SandwichSheetLaminateTab, 'text');
            app.SanLoadInputsButton = uibutton(app.SandwichSheetLaminateTab, 'push');
            app.SanLoadInputsButton.ButtonPushedFcn = createCallbackFcn(app, @SanLoadInputsButtonPushed, true);
            app.SanLoadInputsButton.Text = 'Load Inputs';
            app.LaminateFailureOrientationTab = uitab(app.TabGroup);
            app.LaminateFailureOrientationTab.Title = 'Laminate Failure Orientation';
            app.Label_10 = uilabel(app.LaminateFailureOrientationTab);
            app.Label_10.Text = '3.';
            app.Label_11 = uilabel(app.LaminateFailureOrientationTab);
            app.Label_11.Text = '2.';
            app.Label_12 = uilabel(app.LaminateFailureOrientationTab);
            app.Label_12.Text = '1.';
            app.LaminateFailurePropertiesLabel_3 = uilabel(app.LaminateFailureOrientationTab);
            app.LaminateFailurePropertiesLabel_3.Text = 'Laminate Failure Properties';
            app.LamFailProp = uitable(app.LaminateFailureOrientationTab);
            app.LamFailProp.ColumnName = {'Xt'; 'Xc'; 'Yt'; 'Yc'; 'S'; 'ext'; 'exc'; 'eyt'; 'eyc'; 'es'};
            app.LamFailProp.RowName = {};
            app.LamFailResults = uitable(app.LaminateFailureOrientationTab);
            app.LamFailResults.ColumnName = {'Theta'; 'Failure Pressure'};
            app.LamFailResults.RowName = {};
            app.LamFailHoffman = uibutton(app.LaminateFailureOrientationTab, 'push');
            app.LamFailHoffman.ButtonPushedFcn = createCallbackFcn(app, @LamFailHoffmanPushed, true);
            app.LamFailHoffman.Text = 'Hoffman';
            app.LaminateMaterialDimensionPropertiesLabel_2 = uilabel(app.LaminateFailureOrientationTab);
            app.LaminateMaterialDimensionPropertiesLabel_2.Text = 'Laminate Material & Dimension Properties';
            app.LamFailMatProp = uitable(app.LaminateFailureOrientationTab);
            app.LamFailMatProp.ColumnName = {'E1(Pa)'; 'E2(Pa)'; 'G12(Pa)'; 'v12'; 'a(m)'; 'b(m)'; 'h(m)'; 'Rho(kg/m^-3)'};
            app.LamFailMatProp.RowName = {'Laminate'};
            app.LamFailMatProp.ColumnEditable = true;
            app.NumberOfPliesEditFieldLabel_2 = uilabel(app.LaminateFailureOrientationTab);
            app.NumberOfPliesEditFieldLabel_2.Text = 'Number Of Plies';
            app.NumberOfPliesEditField = uieditfield(app.LaminateFailureOrientationTab, 'numeric');
            app.NumberOfPliesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfPliesEditFieldValueChanged, true);
            app.LamFailTsaiHill = uibutton(app.LaminateFailureOrientationTab, 'push');
            app.LamFailTsaiHill.ButtonPushedFcn = createCallbackFcn(app, @LamFailTsaiHillPushed, true);
            app.LamFailTsaiHill.Text = 'Tsai-Hill';
            app.LamFailTsaiWu = uibutton(app.LaminateFailureOrientationTab, 'push');
            app.LamFailTsaiWu.ButtonPushedFcn = createCallbackFcn(app, @LamFailTsaiWuPushed, true);
            app.LamFailTsaiWu.Text = 'Tsai-Wu';
            app.Label_13 = uilabel(app.LaminateFailureOrientationTab);
            app.Label_13.Text = '4.';
            app.ResultsLabel_3 = uilabel(app.LaminateFailureOrientationTab);
            app.ResultsLabel_3.Text = 'Results';
            app.InputFileNameEditField_2Label_2 = uilabel(app.LaminateFailureOrientationTab);
            app.InputFileNameEditField_2Label_2.Text = 'Input File Name';
            app.LamFailInputFileNameEditField = uieditfield(app.LaminateFailureOrientationTab, 'text');
            app.LamFailLoadInputsButton = uibutton(app.LaminateFailureOrientationTab, 'push');
            app.LamFailLoadInputsButton.ButtonPushedFcn = createCallbackFcn(app, @LamFailLoadInputsButtonPushed, true);
            app.LamFailLoadInputsButton.Text = 'Load Inputs';
            app.BlastWavePreviewTab = uitab(app.TabGroup);
            app.BlastWavePreviewTab.Title = 'Blast Wave Preview';
            app.BlastWaveParametersLabel_3 = uilabel(app.BlastWavePreviewTab);
            app.BlastWaveParametersLabel_3.Text = 'Blast Wave Parameters';
            app.PreviewBlastWave = uitable(app.BlastWavePreviewTab);
            app.PreviewBlastWave.ColumnName = {'Pm'; 'Alpha'; 'Tp'};
            app.PreviewBlastWave.RowName = {};
            app.PreviewBlastWave.ColumnEditable = true;
            app.PressOnceFinishedforPreviewButton = uibutton(app.BlastWavePreviewTab, 'push');
            app.PressOnceFinishedforPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PressOnceFinishedforPreviewButtonPushed, true);
            app.PressOnceFinishedforPreviewButton.Text = 'Press Once Finished for Preview';
            app.PressToBeginButton = uibutton(app.BlastWavePreviewTab, 'push');
            app.PressToBeginButton.ButtonPushedFcn = createCallbackFcn(app, @PressToBeginButtonPushed, true);
            app.PressToBeginButton.Text = 'Press To Begin';
            app.UIAxes = uiaxes(app.BlastWavePreviewTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LamApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end